classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        TabGroup           matlab.ui.container.TabGroup
        HistogramTab       matlab.ui.container.Tab
        ImagePanel         matlab.ui.container.Panel
        imgAxes            matlab.ui.control.UIAxes
        ImageUploadButton  matlab.ui.control.Button
        Panel_2            matlab.ui.container.Panel
        histAxes           matlab.ui.control.UIAxes
        Tab2               matlab.ui.container.Tab
        Tab3               matlab.ui.container.Tab
        Tab4               matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImageUploadButton
        function ImageUploadButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'});
            if isequal(file, 0)
                return;
            end
            img = imread(fullfile(path, file));
            imshow(img,'Parent',app.imgAxes);
            axes(app.histAxes);
            histogram(app.histAxes,img);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.Panel_2 = uipanel(app.HistogramTab);
            app.Panel_2.Title = 'Panel';
            app.histAxes = uiaxes(app.Panel_2);
            title(app.histAxes, 'Title')
            xlabel(app.histAxes, 'X')
            ylabel(app.histAxes, 'Y')
            zlabel(app.histAxes, 'Z')
            app.ImageUploadButton = uibutton(app.HistogramTab, 'push');
            app.ImageUploadButton.ButtonPushedFcn = createCallbackFcn(app, @ImageUploadButtonPushed, true);
            app.ImageUploadButton.Text = 'Image Upload';
            app.ImagePanel = uipanel(app.HistogramTab);
            app.ImagePanel.Title = 'Image';
            app.imgAxes = uiaxes(app.ImagePanel);
            app.imgAxes.XTick = [];
            app.imgAxes.YTick = [];
            app.imgAxes.ZTick = [];
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end